classdef app_decode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        TextArea  matlab.ui.control.TextArea
        Button    matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonPushed(app, event)
            value = app.Button.Value;
            if value == 1
                R = audiorecorder(48000, 16 ,1) ;
                record(R);
                pause(30);
                stop(R);
                message = getaudiodata(R);
                audiowrite("tmp.wav", message, 48000);
                message_char = message_receiver_func();
                app.TextArea.Value = message_char;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '接收解码程序';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '解码结果：';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'正在解码。。。'};
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '录音并解码';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_decode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end