classdef app_decode_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Label         matlab.ui.control.Label
        Label_2       matlab.ui.control.Label
        TextArea      matlab.ui.control.TextArea
        decodebutton  matlab.ui.control.StateButton
        recordbutton  matlab.ui.control.StateButton
        clearbutton   matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: recordbutton
        function RecordButtonValueChanged(app, event)
            value = app.recordbutton.Value;
            if value == 1 
                app.TextArea.Value = {'正在录音'};
                message_recorder_func();
                app.TextArea.Value = {'录音结束'};
            end
        end

        % Callback function
        function R(app, event)
            value = app.recordbutton.Value;
            
        end

        % Value changed function: clearbutton
        function ClearButtonValueChanged(app, event)
            value = app.clearbutton.Value;
             if value == 1 
                app.TextArea.Value = {'等待命令'};
            end
        end

        % Value changed function: decodebutton
        function DecodeButtonValueChanged(app, event)
            value = app.decodebutton.Value;
            if value == 1 
                app.TextArea.Value = {'正在解码'};
                message = message_receiver_func();
                app.TextArea.Value = message;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '接收解码程序';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '解码结果：';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'正在解码。。。'};
            app.decodebutton = uibutton(app.UIFigure, 'state');
            app.decodebutton.ValueChangedFcn = createCallbackFcn(app, @DecodeButtonValueChanged, true);
            app.decodebutton.Text = '开始解码';
            app.recordbutton = uibutton(app.UIFigure, 'state');
            app.recordbutton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.recordbutton.Text = '开始录音';
            app.clearbutton = uibutton(app.UIFigure, 'state');
            app.clearbutton.ValueChangedFcn = createCallbackFcn(app, @ClearButtonValueChanged, true);
            app.clearbutton.Text = '清空';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_decode_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end